classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataCollectionUIFigure      matlab.ui.Figure
        InterpoleraCheckBox         matlab.ui.control.CheckBox
        VisakonsolButton            matlab.ui.control.Button
        Console                     matlab.ui.control.TextArea
        Lamp                        matlab.ui.control.Lamp
        SamplingshasighetEditField  matlab.ui.control.NumericEditField
        SamplingshasighetEditFieldLabel  matlab.ui.control.Label
        ChooseDeviceDropDown        matlab.ui.control.DropDown
        VljenhetDropDownLabel       matlab.ui.control.Label
        ClearButton                 matlab.ui.control.Button
        OpenFileButton              matlab.ui.control.Button
        SaveFileButton              matlab.ui.control.Button
        StartStopButton             matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        dq
        data = timetable()
        meanData = timetable()
        dataTmp
        counter = 0
        running = false
        viewing = false;
        reading = false;
        interpolate = false;
        file = ""
        path = ""
        rate = 10
    end

    properties (Constant)
        title = "Data Collection"
        captureTime = 1
    end
    
    methods (Access = private)
        
        function Init(app)
            app.SamplingshasighetEditField.Value = app.rate;
            app.dq = daq("ni");
            app.dq.Rate = app.rate;
            app.ChooseDeviceDropDown.Items = daqlist("ni").Model + " (" + daqlist("ni").DeviceID + ")";
            app.ChooseDeviceDropDown.ItemsData = daqlist("ni").DeviceID;
            try
                addinput(app.dq, app.ChooseDeviceDropDown.Value, "ai0", "Voltage");
            catch
                
            end
        end
        
        function ReadData(app)
            while app.running
                try
                    app.reading = true;
                    log(app, "Reading data...");
                    app.dataTmp = read(app.dq, seconds(app.captureTime));
                catch
                    app.reading = false;
                    log(app, "Read failed!", "error");
                    app.Lamp.Enable = false;
                    break;
                end
                
                app.reading = false;

                if ~app.running
                    app.dataTmp = 0;
                    app.Lamp.Enable = false;
                    break;
                end
                app.dataTmp.Time = app.dataTmp.Time + seconds(app.counter * app.captureTime);
                app.dataTmp.Time.Format = 'hh:mm:ss.S';
                app.dataTmp.Variables = app.dataTmp.Variables + 1;
                app.data = [app.data; app.dataTmp];
                app.dataTmp = 0;
                app.counter = app.counter + 1;
                
                if ~app.interpolate
                    plot(app.UIAxes, app.data.Time, app.data.Variables);
                else
                    app.meanData = retime(app.data, "regular","median","TimeStep", seconds(app.captureTime) * 5 / (app.rate));
                    %app.meanData = retime(app.data, "regular","makima","TimeStep", 5 * sum(app.data.Time)/numel(app.data.Time));
                    
                    %hold(app.UIAxes,"on");
                    plot(app.UIAxes, app.meanData.Time, app.meanData.Variables);
                    %hold(app.UIAxes,"off");
                end
            end
        end
        
        function SaveFile(app)
            [app.file, app.path] = uiputfile("untitled.txt");
            if app.path == 0
                app.path = "";
                return;
            else
                app.path = append(app.path, app.file);
                writetimetable(app.data, app.path);
                app.DataCollectionUIFigure.Name = append(app.title, " - ", app.path);
            end
        end
        
        function log(app, message, logLevel)
            if nargin == 2
                logLevel = "info";
            end

            if logLevel == "error" || logLevel == "Error"
                prefix = "[ERROR] " + datestr(datetime("now"), "[yyyy-MM-dd HH:mm:ss]");
            elseif logLevel == "warning" || logLevel == "Warning"
                prefix = "[WARNING] " + datestr(datetime("now"), "[yyyy-MM-dd HH:mm:ss]");
            else
                prefix = "[INFO] " + datestr(datetime("now"), "[yyyy-MM-dd HH:mm:ss]");
            end
            if app.Console.Value == ""
                app.Console.Value = prefix + ": " + message;
            else
                app.Console.Value = [app.Console.Value; prefix + ": " + message];
            end
            scroll(app.Console, "bottom");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Init(app);
        end

        % Button pushed function: StartStopButton
        function StartStopButtonPushed(app, event)
            if app.viewing
                ClearButtonPushed(app, event);
            end
            
            if ~app.viewing
                app.running = ~app.running;
                if app.running; app.Lamp.Enable = true; end
                ReadData(app);
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.running = false;
            selection = uiconfirm(app.DataCollectionUIFigure, "Är du säker på att du vill rensa?", "Rensa diagrammet", ...
                "Options", ["OK", "Avbryt"], "DefaultOption", 2, "CancelOption", 2);
            switch selection
                case "OK"
                    app.data = timetable();
                    app.counter = 0;
                    cla(app.UIAxes);
                    app.viewing = false;
                    app.path = "";
                    app.file = "";
                    app.DataCollectionUIFigure.Name = app.title;
                case "Avbryt"
                    return;
            end
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
            app.running = false;
            selection = uiconfirm(app.DataCollectionUIFigure, "Hur vill du spara?", "Spara fil", ...
                "Options", ["Spara", "Spara som", "Avbryt"], "DefaultOption", 2, "CancelOption", 3);
            switch selection
                case "Spara"
                    if app.path == ""
                        SaveFile(app);
                    else
                        writetimetable(app.data, app.path);
                    end
                case "Spara som"
                    SaveFile(app);
                case "Avbryt"
                    return;
            end
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            app.running = false;
            [app.file, app.path] = uigetfile("*.txt", "Open");

            if app.path == 0
                return;
            elseif endsWith(app.file,".txt")
                app.path = append(app.path, app.file);
                app.data = readtimetable(app.path);
                app.DataCollectionUIFigure.Name = append(app.title, " - ", app.path);
                plot(app.UIAxes, app.data.Time, app.data.Variables);
                app.viewing = true;
            else
                app.path = "";
                app.file = "";
                errordlg("Fel filformat", "Fel");
            end
        end

        % Value changed function: ChooseDeviceDropDown
        function ChooseDeviceDropDownValueChanged(app, event)
            try
                removechannel(app.dq, 1);
                addinput(app.dq, app.ChooseDeviceDropDown.Value, "ai0", "Voltage");
            catch
                
            end
        end

        % Value changed function: SamplingshasighetEditField
        function SamplingshasighetEditFieldValueChanged(app, event)
            try
                if app.SamplingshasighetEditField.Value < 2
                    app.SamplingshasighetEditField.Value = app.rate;
                    return;
                end
                app.dq.Rate = app.SamplingshasighetEditField.Value;
                app.rate = app.SamplingshasighetEditField.Value;
            catch
                app.SamplingshasighetEditField.Value = app.rate;
            end
        end

        % Button pushed function: VisakonsolButton
        function VisakonsolButtonPushed(app, event)
%             if app.Console.Visible
%                 app.DataCollectionUIFigure.Position = [100, 100, 640, 497];
%                 app.DataCollectionUIFigure.set()
%             else
%                 app.DataCollectionUIFigure.Position = [100, 100, 640, 595];
%             end

            app.Console.Visible = ~app.Console.Visible;
        end

        % Value changed function: InterpoleraCheckBox
        function InterpoleraCheckBoxValueChanged(app, event)
            app.interpolate = app.InterpoleraCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataCollectionUIFigure = uifigure('Visible', 'off');
            app.DataCollectionUIFigure.Name = 'Data Collection';
            app.UIAxes = uiaxes(app.DataCollectionUIFigure);
            title(app.UIAxes, 'Spänningsdiagram')
            xlabel(app.UIAxes, 'Tid')
            ylabel(app.UIAxes, 'Spänning (V)')
            zlabel(app.UIAxes, 'Z')
            app.StartStopButton = uibutton(app.DataCollectionUIFigure, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'Start/Stopp';
            app.SaveFileButton = uibutton(app.DataCollectionUIFigure, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Text = 'Spara fil';
            app.OpenFileButton = uibutton(app.DataCollectionUIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Öppna fil';
            app.ClearButton = uibutton(app.DataCollectionUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Rensa';
            app.VljenhetDropDownLabel = uilabel(app.DataCollectionUIFigure);
            app.VljenhetDropDownLabel.Text = 'Välj enhet';
            app.ChooseDeviceDropDown = uidropdown(app.DataCollectionUIFigure);
            app.ChooseDeviceDropDown.Items = {};
            app.ChooseDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseDeviceDropDownValueChanged, true);
            app.ChooseDeviceDropDown.Value = {};
            app.SamplingshasighetEditFieldLabel = uilabel(app.DataCollectionUIFigure);
            app.SamplingshasighetEditFieldLabel.Text = 'Samplingshasighet';
            app.SamplingshasighetEditField = uieditfield(app.DataCollectionUIFigure, 'numeric');
            app.SamplingshasighetEditField.Limits = [-0.2 1000];
            app.SamplingshasighetEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingshasighetEditFieldValueChanged, true);
            app.SamplingshasighetEditField.Value = 1;
            app.Lamp = uilamp(app.DataCollectionUIFigure);
            app.Lamp.Enable = 'off';
            app.Console = uitextarea(app.DataCollectionUIFigure);
            app.Console.Editable = 'off';
            app.Console.Visible = 'off';
            app.VisakonsolButton = uibutton(app.DataCollectionUIFigure, 'push');
            app.VisakonsolButton.ButtonPushedFcn = createCallbackFcn(app, @VisakonsolButtonPushed, true);
            app.VisakonsolButton.Text = 'Visa konsol';
            app.InterpoleraCheckBox = uicheckbox(app.DataCollectionUIFigure);
            app.InterpoleraCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterpoleraCheckBoxValueChanged, true);
            app.InterpoleraCheckBox.Text = 'Interpolera';
            % Show the figure after all components are created
            app.DataCollectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataCollectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataCollectionUIFigure)
        end
    end
end